classdef milkweed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ChooseRuntoLoadLabel    matlab.ui.control.Label
        RunSelect               matlab.ui.control.DropDown
        PlotArea                matlab.ui.control.UIAxes
        LoadButton              matlab.ui.control.Button
        PlotTypeLabel           matlab.ui.control.Label
        PlotSelect              matlab.ui.control.DropDown
        SummaryTextAreaLabel    matlab.ui.control.Label
        SummaryTextArea         matlab.ui.control.TextArea
        PlotButton              matlab.ui.control.Button
        RunSelectTextAreaLabel  matlab.ui.control.Label
        RunSelectTextArea       matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        

    end
            
    %% Helper Variables
    properties (Access = public)
        run_data %struct containing data from monte carlo runs
        expected_rates = 5; %deg / s
        data_point_index = 0;
        x_color = [0 0.4470 0.7410];
        y_color = [0.8500 0.3250 0.0980];
        z_color = [0.9290 0.6940 0.1250];
    end
    
    %% Helper Functions
    methods (Access = public)
        
        %Function combs previously stored runs to load them all for the
        %app.
        function runs = run_lister(app)
            cd run_storage
            temp = dir('*.mat');
            [num_stored,~] = size(temp);
            
            
            runs = cell(int8(num_stored + 1), 1);
            runs{1} = '---';
            for i = 1:num_stored
                parts = strsplit(temp(i).name, '.');
                runs{i+1} = parts{1};
            end
            cd ../
        end
        
        %Function loads data and ties it to app, that way data doesn't need
        %to be re-loaded for every op.
        function dump = load_run(app, run_name)
            run_name = strcat(run_name, '.mat');
            cd run_storage;
            dump = load(run_name);
            file_info = dir(run_name);
            dump.date = file_info.date;
            
            %Get the heck out of there
            cd ../
        end
        
        %Quick and easy function to pull sample size
        function sample_size = run_size(app)
            [sample_size,~] = size(app.run_data.initial_rates);
        end
        
        %Function will give the run time statistics if that given run
        function [mu_time, sigma_time] = time_stats(app)
            [in_spec_tf, ~, ~, ~] = terminal_rate_check(app);
            good_times = app.run_data.total_time(in_spec_tf);
            mu_time = mean(good_times);
            sigma_time = std(good_times);
        end
        
        %This function will let us know whether or not the rates at the end of the simulation are within the desired range.
        %The first output will cross check all three individual axes.
        %The following three outputs look at each invidual access
        function [in_spec_tf, x_in_spec_tf, y_in_spec_tf, z_in_spec_tf] = terminal_rate_check(app)
            terminal_rates = app.run_data.terminal_rates;
            threshold = deg2rad(1); %Given threshold of 1 deg/s per axis
            
            %logical matrix to comb through the rates output by the sim and
            %check they are under our threshold.
            in_or_out_tf = zeros(size(terminal_rates));
            for i = 1:3
                in_or_out_tf(:,i) = abs(terminal_rates(:,i)) < threshold;
            end
            
            x_in_spec_tf = in_or_out_tf(:,1);
            y_in_spec_tf = in_or_out_tf(:,2); 
            z_in_spec_tf = in_or_out_tf(:,3);
            
            in_spec_tf = (in_or_out_tf(:,1)) & (in_or_out_tf(:,2)) & (in_or_out_tf(:,3));
        end
        
        %Function to see how many, if at all, had a rate increase about a
        %respective axis.
        function in_excess = excess_checker(app)
            initial_rates = app.run_data.initial_rates;
            terminal_rates = app.run_data.terminal_rates;
            [~, x_in_spec_tf, y_in_spec_tf, z_in_spec_tf] = terminal_rate_check(app);
            in_spec = [x_in_spec_tf,y_in_spec_tf,z_in_spec_tf];
            
            in_excess = zeros(3,1);
            for i=1:3
              in_excess(i) = sum((abs(terminal_rates(:,i)) > abs(initial_rates(:,i)))&(~in_spec(:,i)));        
            end
            
            
        end
        
        %Function to find the magnitude of the run data.
        function magnitudes = mag_finder(app, data_in)
            magnitudes = vecnorm(data_in,2,2);  
        end
        
        %Function to make a scatter plot of the run data in different
        %configurations based on what is selected by the plot.
        function scattered_rates(app, config)
            sample_size = run_size(app);
            marker_size = 50;
            line_width = 1.5;
            switch config
                case "All"
                    row_index = [1:sample_size];
                    col_index = [1:3];
                    %Keeping consistent color scheme between single plots
                    color = {app.x_color, app.y_color, app.z_color};
                case "X"
                    row_index = [1:sample_size];
                    col_index = 1;
                    color = app.x_color;
                case "Y"
                    row_index = [1:sample_size];
                    col_index = 2;
                    color = app.y_color;
                case "Z"
                    row_index = [1:sample_size];
                    col_index = 3;
                    color = app.z_color;
                case "Failed"
                    row_index = [1:sample_size];
                    in_spec_tf = terminal_rate_check(app);
                    row_index = row_index(~in_spec_tf); %Selecting the failed runs
                    col_index = [1:3];
                    %Keeping consistent color scheme between single plots
                    color = {app.x_color, app.y_color, app.z_color};
            end
            
            if length(col_index) > 1 % Basically the plots that show compiled rates across all three axes
                for i = 1:3
                    scatter(app.PlotArea,rad2deg(app.run_data.initial_rates(row_index,col_index(i))),rad2deg(app.run_data.terminal_rates(row_index,col_index(i))), marker_size,color{i}, 'LineWidth',line_width)
                    hold(app.PlotArea, "on");
                end
                legend(app.PlotArea, {'{\omega_x}', '{\omega_y}','{\omega_z}'})
            else
               scatter(app.PlotArea,rad2deg(app.run_data.initial_rates(row_index,col_index)),rad2deg(app.run_data.terminal_rates(row_index,col_index)), marker_size,color, 'LineWidth',line_width) 
            end
            hold(app.PlotArea, "on");
            upper_lim = yline(app.PlotArea, 1, '--k');
            lower_lim = yline(app.PlotArea,-1, '--k');
            app.PlotArea.XLabel.String = "Intial Rate [deg/s]";
            app.PlotArea.YLabel.String = "Terminal Rate [deg/s]";
            cursorMode = datacursormode(app.UIFigure);
            set(cursorMode, 'enable','on', 'UpdateFcn',@data_tip);
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            runs = run_lister(app);
            app.RunSelect.Items = runs;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            run_name = convertCharsToStrings(app.RunSelect.Value);
            load = load_run(app, run_name);
            
            %Break out the struct - one less layer to dig through
            app.run_data.initial_rates = load.data_store.initial_rates;
            app.run_data.terminal_rates = load.data_store.terminal_rates;
            app.run_data.total_time = load.data_store.total_time;
            app.run_data.date = load.date;
            app.run_data.margin = load.data_store.margin;
            
            %Generate Stats for Summary Box
            sample_size = run_size(app);
            [in_spec_tf, x_in_spec, y_in_spec, z_in_spec] = terminal_rate_check(app);
            num_pass = sum(in_spec_tf); %sum of logical array will tell us how many made it
            pass_rate = (num_pass / sample_size) * 100;
            [average_time, stdev_time] = time_stats(app);
            in_excess = excess_checker(app);
            
            if(datenum(app.run_data.date) < datenum("14-Apr-2022 09:51:02")) % Previous runs had included 1 which technically isn't what margin means.
                rate_range = (app.run_data.margin) * (-app.expected_rates + 2 * app.expected_rates * [0 1]);
            else
                rate_range = (app.run_data.margin + 1) * (-app.expected_rates + 2 * app.expected_rates * [0 1]);
            end
            
            
            %Print out to Summary
            app.SummaryTextArea.Value{1} = convertStringsToChars(sprintf("Run Date: %s", app.run_data.date));
            app.SummaryTextArea.Value{2} = convertStringsToChars(sprintf("Sample Size: %d runs", sample_size));
            app.SummaryTextArea.Value{3} = convertStringsToChars(sprintf("Passing Rate: %.2g Percent", pass_rate));
            app.SummaryTextArea.Value{4} = convertStringsToChars(sprintf("Rate Range: [%1.1d, %1.1d]",rate_range(1), rate_range(2))); %May need to comeback and fix this
            app.SummaryTextArea.Value{5} = convertStringsToChars(sprintf("Detumble Time Average of Passing Runs: %4.2f s", average_time));
            app.SummaryTextArea.Value{6} = convertStringsToChars(sprintf("Detumble Time St. Dev of Passing Runs: %4.2f s", stdev_time));
            app.SummaryTextArea.Value{7} = (' ');
            app.SummaryTextArea.Value{8} = ('Failure Breakdown:');
            app.SummaryTextArea.Value{9} = convertStringsToChars(sprintf("Rates about x-axis: %d of %d runs failed", sum(~x_in_spec), sample_size));
            app.SummaryTextArea.Value{10} = convertStringsToChars(sprintf("Rates about y-axis: %d of %d runs failed", sum(~y_in_spec), sample_size));
            app.SummaryTextArea.Value{11} = convertStringsToChars(sprintf("Rates about z-axis: %d of %d runs failed", sum(~z_in_spec), sample_size));
            app.SummaryTextArea.Value{12} = (' ');
            app.SummaryTextArea.Value{13} = ('Rate Increases:');
            app.SummaryTextArea.Value{14} = convertStringsToChars(sprintf("Magnitude Increase about x-axis (out of bounds): %d of %d runs increased", in_excess(1), sample_size));
            app.SummaryTextArea.Value{15} = convertStringsToChars(sprintf("Magnitude Increase about y-axis (out of bounds): %d of %d runs increased", in_excess(2), sample_size));
            app.SummaryTextArea.Value{16} = convertStringsToChars(sprintf("Magnitude Increase about z-axis (out of bounds): %d of %d runs increased", in_excess(3), sample_size));


            %In case you want to load data from another run
            cla(app.PlotArea);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if isempty(app.run_data)
                msgbox("You have to load data before you can plot it!","Error","error");
                return
            end
            cla(app.PlotArea);
            switch app.PlotSelect.Value %What is the current plot selection on the app
                case "Bulk Histogram"
                    magnitudes = mag_finder(app, app.run_data.initial_rates);
                    [in_spec_tf, ~, ~, ~] = terminal_rate_check(app);
            
                    %Starting stacked histogram - https://stackoverflow.com/questions/60609212/how-to-generate-a-stacked-histogram-from-different-sized-matrices#:~:text=MATLAB%27s%20bar%20method%20has%20a%20%27stacked%27%20option.%20It%27s,achieve%20that%20by%20properly%20setting%20the%20xbins%20parameter.
                    %Sorting will be required
                    [magnitudes, sorted_indices] = sort(magnitudes);
                    magnitudes = rad2deg(magnitudes);
                    bins = 0:ceil(max(magnitudes));
                    
                    %Depending on which group is smaller will dictate which
                    %goes in the back or the front.
                    
                    %First / "bottom" layer is all the data. This gives the
                    %"stacked" illusion.
                    histogram(app.PlotArea, magnitudes,bins, 'FaceColor', [0.8500 0.3250 0.0980]);
                    hold(app.PlotArea, "on");
                    
                    if (sum(in_spec_tf) < sum(~in_spec_tf))
                    %Group in spec added on top
                    histogram(app.PlotArea, magnitudes(in_spec_tf(sorted_indices)),bins, 'FaceColor', [0 0.4470 0.7410]);
                    legend(app.PlotArea,'Failed Runs','Passing Runs');
                    else 
                    %Group out of spec added on top
                    histogram(app.PlotArea, magnitudes(~in_spec_tf(sorted_indices)),bins, 'FaceColor', [0 0.4470 0.7410]);
                    legend(app.PlotArea,'Passing Runs', 'Failed Runs');    
                    end
                    
                    app.PlotArea.XLabel.String = "Initial Angular Velocity Magnitude [deg/s]";
                    app.PlotArea.YLabel.String = " ";
                
                case "Initial vs Final - All"
                    scattered_rates(app, "All")
                case "Initial vs Final - X"
                    scattered_rates(app, "X")
                case "Initial vs Final - Y"
                    scattered_rates(app, "Y")
                case "Initial vs Final - Z"
                    scattered_rates(app, "Z")
                case "Failed Runs"
                    scattered_rates(app, "Failed")
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseRuntoLoadLabel = uilabel(app.UIFigure);
            app.ChooseRuntoLoadLabel.Text = {'Choose Run to Load'; ''};
            app.RunSelect = uidropdown(app.UIFigure);
            app.RunSelect.Items = {'---'};
            app.RunSelect.Value = '---';
            app.PlotArea = uiaxes(app.UIFigure);
            title(app.PlotArea, '')
            xlabel(app.PlotArea, '')
            ylabel(app.PlotArea, {''; ''})
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlotTypeLabel = uilabel(app.UIFigure);
            app.PlotTypeLabel.Text = {'Plot Type'; ''};
            app.PlotSelect = uidropdown(app.UIFigure);
            app.PlotSelect.Items = {'---', 'Bulk Histogram', 'Initial vs Final - All', 'Initial vs Final - X', 'Initial vs Final - Y', 'Initial vs Final - Z', 'Failed Runs'};
            app.PlotSelect.Value = '---';
            app.SummaryTextAreaLabel = uilabel(app.UIFigure);
            app.SummaryTextAreaLabel.Text = {'Summary'; ''};
            app.SummaryTextArea = uitextarea(app.UIFigure);
            app.SummaryTextArea.Value = {'Load a run!'};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RunSelectTextAreaLabel = uilabel(app.UIFigure);
            app.RunSelectTextAreaLabel.Text = {'Run Select'; ''};
            app.RunSelectTextArea = uitextarea(app.UIFigure);
            app.RunSelectTextArea.Value = {'Use the data tips on a scatter plot !'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = milkweed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end